classdef aom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Wczytaj_zdjcieButton  matlab.ui.control.Button
        Pokaz_wynikButton     matlab.ui.control.Button
        Pokaz_maskButton      matlab.ui.control.Button
        WysegmentowaniezmianynowotworowejnaobrazieUSGLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        Zapisz_obrazButton    matlab.ui.control.Button
        Zapisz_maskeButton    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Zapisz_obrazButton.Visible = 'off'
            app.Zapisz_maskeButton.Visible = 'off';
        end

        % Button pushed function: Wczytaj_zdjcieButton
        function Wczytaj_zdjcieButtonPushed(app, event)
            global original;
            global mask;
            
%             [filename pathname] = uigetfile({'*.png'}, 'File Selector');
%             fullpath = strcat(pathname, filename);
%             original = fileread(fullpath);

           original = imread("C:\Users\Marta\Desktop\aom_projekt\program\BUS\original\000025.png");
           mask = imread("C:\Users\Marta\Desktop\aom_projekt\program\BUS\GT\000025.png");
            
           imshow(original, 'Parent', app.UIAxes);
            
        end

        % Button pushed function: Pokaz_wynikButton
        function Pokaz_wynikButtonPushed(app, event)
            global original;
            global mask;
            global GLCM;
            
            glcm = graycomatrix(original,'Offset',[2 0]);
            offsets = [0 1; -1 1;-1 0;-1 -1];
            [glcms,SI] = graycomatrix(original,'Offset',offsets);
            GLCM = rescale(SI);
            
            global mask_glcm;
            mask_glcm = activecontour(rescale(SI), mask, 10, 'edge');
            
            KONTUR = visboundaries(mask_glcm,'Color','r');
            
            
            imshow(GLCM, 'Parent', app.UIAxes_2);
        
            hold (app.UIAxes,"on")
            imshow(KONTUR);
%             KONTUR;
            hold (app.UIAxes,"off")
            
           
            app.Zapisz_obrazButton.Visible = 'on';
        end

        % Button pushed function: Pokaz_maskButton
        function Pokaz_maskButtonPushed(app, event)
          global mask_glcm;
          imshow(mask_glcm,'Parent',app.UIAxes_3);
          
          app.Zapisz_maskeButton.Visible = 'on';
        end

        % Button pushed function: Zapisz_obrazButton
        function Zapisz_obrazButtonPushed(app, event)
            global GLCM;
            imwrite(GLCM,'obraz_wynikowy.png');
        end

        % Button pushed function: Zapisz_maskeButton
        function Zapisz_maskeButtonPushed(app, event)
            global mask_glcm;
            imwrite(mask_glcm, 'maska_wynikowa.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Wczytaj_zdjcieButton = uibutton(app.UIFigure, 'push');
            app.Wczytaj_zdjcieButton.ButtonPushedFcn = createCallbackFcn(app, @Wczytaj_zdjcieButtonPushed, true);
            app.Wczytaj_zdjcieButton.Text = 'Wczytaj obraz';
            app.Pokaz_wynikButton = uibutton(app.UIFigure, 'push');
            app.Pokaz_wynikButton.ButtonPushedFcn = createCallbackFcn(app, @Pokaz_wynikButtonPushed, true);
            app.Pokaz_wynikButton.Text = 'Wynik';
            app.Pokaz_maskButton = uibutton(app.UIFigure, 'push');
            app.Pokaz_maskButton.ButtonPushedFcn = createCallbackFcn(app, @Pokaz_maskButtonPushed, true);
            app.Pokaz_maskButton.Text = 'Pokaż maskę';
            app.WysegmentowaniezmianynowotworowejnaobrazieUSGLabel = uilabel(app.UIFigure);
            app.WysegmentowaniezmianynowotworowejnaobrazieUSGLabel.Text = 'Wysegmentowanie zmiany nowotworowej na obrazie USG';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Obraz oryginalny')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Obraz wynikowy')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Maska obrazu wynikowego')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.Zapisz_obrazButton = uibutton(app.UIFigure, 'push');
            app.Zapisz_obrazButton.ButtonPushedFcn = createCallbackFcn(app, @Zapisz_obrazButtonPushed, true);
            app.Zapisz_obrazButton.Text = 'Zapisz Obraz';
            app.Zapisz_maskeButton = uibutton(app.UIFigure, 'push');
            app.Zapisz_maskeButton.ButtonPushedFcn = createCallbackFcn(app, @Zapisz_maskeButtonPushed, true);
            app.Zapisz_maskeButton.Text = 'Zapisz maskę';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aom

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end